classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SimulationCompletedSuccessfullyLabel  matlab.ui.control.Label
        SignalInformationPanel         matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        CONTROLSPanel                  matlab.ui.container.Panel
        ShowDemodulatedSignalCheckBox  matlab.ui.control.CheckBox
        ResetButton                    matlab.ui.control.Button
        ActionsLabel                   matlab.ui.control.Label
        GenerateRunSimulationButton    matlab.ui.control.Button
        ModulationIndexSlider          matlab.ui.control.Slider
        ModulationIndexSliderLabel     matlab.ui.control.Label
        CarrierFrequencyfcSlider       matlab.ui.control.Slider
        CarrierFrequencyfcLabel        matlab.ui.control.Label
        MessageFrequencyfmSlider       matlab.ui.control.Slider
        MessageFrequencyfmLabel        matlab.ui.control.Label
        SignalParameterLabel           matlab.ui.control.Label
        SelectModulationDropDown       matlab.ui.control.DropDown
        SelectModulationLabel          matlab.ui.control.Label
        ModulationSimulatorLabel       matlab.ui.control.Label
        ANALOGCOMMUNICATIONVIRTUALLABLabel  matlab.ui.control.Label
        SpectrumAxes                   matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateRunSimulationButton
        function GenerateRunSimulationButtonPushed(app, event)


% =========================================
% COMMON INPUTS
% =========================================
mode = app.SelectModulationDropDown.Value;

fm = app.MessageFrequencyfmSlider.Value;

fc = app.CarrierFrequencyfcSlider.Value;

x = app.ModulationIndexSlider.Value;

fs = 100000;

t = 0:1/fs:0.01;

Am = 1;

Ac = 1;

% Clear Axes
cla(app.UIAxes)
cla(app.SpectrumAxes)

switch mode

% =========================================================
% AM MODULATION + DEMODULATION
% =========================================================
case 'AM-Amplitude Modulation'

    % =====================================
    % CLEAR AXES
    % =====================================
    cla(app.UIAxes)

    % =====================================
    % AM SIGNAL
    % =====================================
    mu = x;

    m = Am*cos(2*pi*fm*t);

    s = Ac*(1 + mu*m).*cos(2*pi*fc*t);

    % =====================================
    % COHERENT DEMODULATION
    % =====================================
    demod = s .* cos(2*pi*fc*t);

    % =====================================
    % LOW PASS FILTER
    % =====================================
    demod = lowpass(demod,fm,fs);

    % =====================================
    % CHECKBOX CONDITION
    % =====================================
    if app.ShowDemodulatedSignalCheckBox.Value

        % DEMODULATED SIGNAL
        plot(app.UIAxes,t,demod,...
        'r','LineWidth',1.5)

        title(app.UIAxes,...
        'Demodulated Signal')

        legend(app.UIAxes,...
        'Demodulated Signal')

    else

        % MODULATED SIGNAL
        plot(app.UIAxes,t,s,...
        'b','LineWidth',1.5)

        title(app.UIAxes,...
        'AM Modulated Signal')

        legend(app.UIAxes,...
        'Modulated Signal')

    end

    % =====================================
    % AXES SETTINGS
    % =====================================
    xlabel(app.UIAxes,'Time (s)')

    ylabel(app.UIAxes,'Amplitude')

    grid(app.UIAxes,'on')

    xlim(app.UIAxes,[0 0.002])

    % =====================================
    % FFT SIGNAL
    % =====================================
    signal = s;

    bw = 2*fm;

    % =====================================
    % TEXT AREA
    % =====================================
    app.TextArea.Value = {

    ['Modulation Type : AM']

    ['Message Frequency : ' num2str(fm) ' Hz']

    ['Carrier Frequency : ' num2str(fc) ' Hz']

    ['Modulation Index : ' num2str(mu)]

    ['Bandwidth : ' num2str(bw) ' Hz']

    ['Status : Simulation Successful']

    };

% =========================================================
% DSB-SC MODULATION + DEMODULATION
% =========================================================
case 'DSB-SC'


    % =====================================
    % CLEAR AXES
    % =====================================
    cla(app.UIAxes)

    % =====================================
    % MESSAGE SIGNAL
    % =====================================
    m = cos(2*pi*fm*t);

    % =====================================
    % CARRIER SIGNAL
    % =====================================
    c = cos(2*pi*fc*t);

    % =====================================
    % DSB-SC MODULATION
    % =====================================
    s = m .* c;

    % =====================================
    % COHERENT DEMODULATION
    % =====================================
    demod = s .* cos(2*pi*fc*t);

    % =====================================
    % LOW PASS FILTER
    % =====================================
    demod = lowpass(demod,fm,fs);

    % =====================================
    % CHECKBOX CONDITION
    % =====================================
    if app.ShowDemodulatedSignalCheckBox.Value

        % DEMODULATED SIGNAL
        plot(app.UIAxes,t,demod,...
        'r','LineWidth',1.5)

        title(app.UIAxes,...
        'DSB-SC Demodulated Signal')

        legend(app.UIAxes,...
        'Recovered Message Signal')

    else

        % MODULATED SIGNAL
        plot(app.UIAxes,t,s,...
        'b','LineWidth',1.5)

        title(app.UIAxes,...
        'DSB-SC Modulated Signal')

        legend(app.UIAxes,...
        'DSB-SC Signal')

    end

    % =====================================
    % AXES SETTINGS
    % =====================================
    xlabel(app.UIAxes,'Time (s)')

    ylabel(app.UIAxes,'Amplitude')

    grid(app.UIAxes,'on')

    xlim(app.UIAxes,[0 0.002])

    % =====================================
    % FFT SIGNAL
    % =====================================
    signal = s;

    bw = 2*fm;

    % =====================================
    % TEXT AREA
    % =====================================
    app.TextArea.Value = {

    ['Modulation Type : DSB-SC']

    ['Message Frequency : ' num2str(fm) ' Hz']

    ['Carrier Frequency : ' num2str(fc) ' Hz']

    ['Bandwidth : ' num2str(bw) ' Hz']

    ['Carrier : Suppressed']

    ['Status : Simulation Successful']

    };
% =========================================================
% SSB-SC MODULATION + DEMODULATION
% =========================================================
case 'SSB-SC'

    % =====================================
    % CLEAR AXES
    % =====================================
    cla(app.UIAxes)

    % =====================================
    % MESSAGE SIGNAL
    % =====================================
    m = cos(2*pi*fm*t);

    % =====================================
    % HILBERT TRANSFORM
    % =====================================
    mh = imag(hilbert(m));

    % =====================================
    % SSB-SC MODULATION
    % =====================================
    s = m.*cos(2*pi*fc*t) - ...
        mh.*sin(2*pi*fc*t);

    % =====================================
    % COHERENT DEMODULATION
    % =====================================
    demod = s .* cos(2*pi*fc*t);

    % =====================================
    % LOW PASS FILTER
    % =====================================
    demod = lowpass(demod,fm,fs);

    % =====================================
    % CHECKBOX CONDITION
    % =====================================
    if app.ShowDemodulatedSignalCheckBox.Value

        % DEMODULATED SIGNAL
        plot(app.UIAxes,t,demod,...
        'r','LineWidth',1.5)

        title(app.UIAxes,...
        'SSB-SC Demodulated Signal')

        legend(app.UIAxes,...
        'Recovered Message Signal')

    else

        % MODULATED SIGNAL
        plot(app.UIAxes,t,s,...
        'b','LineWidth',1.5)

        title(app.UIAxes,...
        'SSB-SC Modulated Signal')

        legend(app.UIAxes,...
        'SSB-SC Signal')

    end

    % =====================================
    % AXES SETTINGS
    % =====================================
    xlabel(app.UIAxes,'Time (s)')

    ylabel(app.UIAxes,'Amplitude')

    grid(app.UIAxes,'on')

    xlim(app.UIAxes,[0 0.002])

    % =====================================
    % FFT SIGNAL
    % =====================================
    signal = s;

    bw = fm;

    % =====================================
    % TEXT AREA
    % =====================================
    app.TextArea.Value = {

    ['Modulation Type : SSB-SC']

    ['Message Frequency : ' num2str(fm) ' Hz']

    ['Carrier Frequency : ' num2str(fc) ' Hz']

    ['Bandwidth : ' num2str(bw) ' Hz']

    ['Single Sideband Transmission']

    ['Carrier : Suppressed']

    ['Status : Simulation Successful']

    };
% =========================================================
% FM MODULATION
% =========================================================
case 'FM'

    % =====================================
    % CLEAR AXES
    % =====================================
    cla(app.UIAxes)

    % =====================================
    % PARAMETERS
    % =====================================
    beta = x;

    fs = 200000;

    t = 0:1/fs:0.005;

    % =====================================
    % MESSAGE SIGNAL
    % =====================================
    m = cos(2*pi*fm*t);

    % =====================================
    % FM MODULATION
    % =====================================
    s = Ac*cos(2*pi*fc*t + ...
        beta*sin(2*pi*fm*t));

    % =====================================
    % FM DEMODULATION
    % =====================================
    analyticSignal = hilbert(s);

    phaseSignal = unwrap(angle(analyticSignal));

    demod = diff(phaseSignal);

    demod = [demod 0];

    % =====================================
    % LOW PASS FILTER
    % =====================================
    demod = lowpass(demod,fm,fs);

    % =====================================
    % NORMALIZATION
    % =====================================
    demod = demod ./ max(abs(demod));

    % =====================================
    % CHECKBOX CONDITION
    % =====================================
    if app.ShowDemodulatedSignalCheckBox.Value

        % DEMODULATED SIGNAL
        plot(app.UIAxes,t,demod,...
        'r','LineWidth',1.5)

        title(app.UIAxes,...
        'FM Demodulated Signal')

        legend(app.UIAxes,...
        'Recovered Message Signal')

    else

        % MODULATED SIGNAL
        plot(app.UIAxes,t,s,...
        'b','LineWidth',1.5)

        title(app.UIAxes,...
        'FM Modulated Signal')

        legend(app.UIAxes,...
        'FM Signal')

    end

    % =====================================
    % AXES SETTINGS
    % =====================================
    xlabel(app.UIAxes,'Time (s)')

    ylabel(app.UIAxes,'Amplitude')

    grid(app.UIAxes,'on')

    xlim(app.UIAxes,[0 0.002])

    % =====================================
    % FFT SIGNAL
    % =====================================
    signal = s;

    bw = 2*(beta*fm + fm);

    % =====================================
    % TEXT AREA
    % =====================================
    app.TextArea.Value = {

    ['Modulation Type : FM']

    ['Message Frequency : ' num2str(fm) ' Hz']

    ['Carrier Frequency : ' num2str(fc) ' Hz']

    ['FM Modulation Index : ' num2str(beta)]

    ['Bandwidth : ' num2str(bw) ' Hz']

    ['Status : Simulation Successful']

    };

% =========================================================
% TDM
% =========================================================
case 'TDM'

    f1 = 20;

    f2 = 5;

    fs = 2000;

    t = 0:1/fs:0.1;

    frame = 20;

    m1 = sin(2*pi*f1*t);

    m2 = square(2*pi*f2*t);

    tdm = zeros(size(t));

    toggle = true;

    for k = 1:frame:length(t)

        idx = k:min(k+frame-1,length(t));

        if toggle

            tdm(idx) = m1(idx);

        else

            tdm(idx) = m2(idx);

        end

        toggle = ~toggle;

    end

    % =====================================
% DEMULTIPLEXING
% =====================================
demux1 = zeros(size(t));

demux2 = zeros(size(t));

toggle = true;

for k = 1:frame:length(t)

    idx = k:min(k+frame-1,length(t));

    if toggle

        demux1(idx) = tdm(idx);

    else

        demux2(idx) = tdm(idx);

    end

    toggle = ~toggle;

end

% =====================================
% CHECKBOX CONDITION
% =====================================
if app.ShowDemodulatedSignalCheckBox.Value

    plot(app.UIAxes,t,demux1,...
    'r','LineWidth',1.5)

    hold(app.UIAxes,'on')

    plot(app.UIAxes,t,demux2,...
    'b','LineWidth',1.5)

    hold(app.UIAxes,'off')

    title(app.UIAxes,...
    'Demultiplexed Signals')

    legend(app.UIAxes,...
    'Recovered Channel 1',...
    'Recovered Channel 2')

else

    stairs(app.UIAxes,t,tdm,...
    'LineWidth',1.5)

    title(app.UIAxes,'TDM Signal')

    legend(app.UIAxes,...
    'TDM Output')

end
    xlabel(app.UIAxes,'Time (s)')

    ylabel(app.UIAxes,'Amplitude')

    grid(app.UIAxes,'on')

    signal = tdm;
    if app.ShowDemodulatedSignalCheckBox.Value

    demod = demux1 + demux2;

else

    demod = tdm;

end

    app.TextArea.Value = {

    ['Modulation Type : TDM']

    ['Channel 1 Frequency : ' num2str(f1) ' Hz']

    ['Channel 2 Frequency : ' num2str(f2) ' Hz']

    ['Status : Simulation Successful']

    };

% =========================================================
% FDM
% =========================================================
case 'FDM'

    f1 = app.MessageFrequencyfmSlider.Value;

    f2 = app.CarrierFrequencyfcSlider.Value;

    fs = 100000;

    t = 0:1/fs:0.01;

    m1 = cos(2*pi*f1*t);

    m2 = sin(2*pi*f2*t);

    c1 = cos(2*pi*1000*t);

    c2 = cos(2*pi*3000*t);

    s1 = m1 .* c1;

    s2 = m2 .* c2;

    fdm = s1 + s2;

    plot(app.UIAxes,t,fdm,...
    'Color',[0.85 0 0.4],...
    'LineWidth',1.5)

    title(app.UIAxes,'FDM Signal')

    xlabel(app.UIAxes,'Time (s)')

    ylabel(app.UIAxes,'Amplitude')

    grid(app.UIAxes,'on')

    xlim(app.UIAxes,[0 0.005])

    signal = fdm;

    app.TextArea.Value = {

    ['Modulation Type : FDM']

    ['Message Frequency 1 : ' num2str(f1) ' Hz']

    ['Message Frequency 2 : ' num2str(f2) ' Hz']

    ['Carrier 1 : 1000 Hz']

    ['Carrier 2 : 3000 Hz']

    ['Status : Simulation Successful']

    };

end

% =========================================================
% FFT SPECTRUM
% =========================================================
% =========================================================
% SELECT SIGNAL FOR FFT
% =========================================================

if app.ShowDemodulatedSignalCheckBox.Value

    % Use demodulated signal spectrum
    signalFFT = demod;

else

    % Use modulated signal spectrum
    signalFFT = signal;

end

% =========================================================
% FFT CALCULATION
% =========================================================
N = length(signalFFT);

Y = fft(signalFFT);

P2 = abs(Y/N);

P1 = P2(1:N/2+1);

P1(2:end-1) = 2*P1(2:end-1);

f = fs*(0:(N/2))/N;

% =========================================================
% PLOT FFT SPECTRUM
% =========================================================
plot(app.SpectrumAxes,f,P1,...
'Color',[0.85 0 0.3],...
'LineWidth',1.5)

% =========================================================
% TITLE CHANGE
% =========================================================
if app.ShowDemodulatedSignalCheckBox.Value

    title(app.SpectrumAxes,...
    'Demodulated Signal Spectrum')

else

    title(app.SpectrumAxes,...
    'Modulated Signal Spectrum')

end

xlabel(app.SpectrumAxes,'Frequency (Hz)')

ylabel(app.SpectrumAxes,'Magnitude')

grid(app.SpectrumAxes,'on')

xlim(app.SpectrumAxes,[0 20000])

% =========================================================
% FIND DOMINANT FREQUENCY
% =========================================================
[~,idx] = max(P1);

dominantFreq = f(idx);

% =========================================================
% UPDATE TEXT AREA
% =========================================================
oldText = app.TextArea.Value;

app.TextArea.Value = [oldText;
{['Dominant Frequency : ' ...
num2str(dominantFreq,'%.2f') ' Hz']}];

% =========================================================
% DEMODULATION STATUS
% =========================================================
if app.ShowDemodulatedSignalCheckBox.Value

    app.TextArea.Value = ...
    [app.TextArea.Value;
    {'Spectrum Mode : Demodulated Signal'}];

else

    app.TextArea.Value = ...
    [app.TextArea.Value;
    {'Spectrum Mode : Modulated Signal'}];

end
        end

        % Value changed function: SelectModulationDropDown
        function SelectModulationDropDownValueChanged(app, event)
          

value = app.SelectModulationDropDown.Value;

switch value

    % =========================================
    % AM
    % =========================================
    case 'AM-Amplitude Modulation'

        app.ModulationIndexSlider.Visible = 'on';
        app.ModulationIndexSliderLabel.Visible = 'on';

        app.ModulationIndexSliderLabel.Text = ...
        'Modulation Index';

        app.MessageFrequencyfmLabel.Text = ...
        'Message Frequency (fm)';

        app.CarrierFrequencyfcLabel.Text = ...
        'Carrier Frequency (fc)';

        app.MessageFrequencyfmSlider.Limits = [100 5000];
        app.CarrierFrequencyfcSlider.Limits = [5000 20000];


    % =========================================
    % DSB-SC
    % =========================================
    case 'DSB-SC'

        app.ModulationIndexSlider.Visible = 'off';
        app.ModulationIndexSliderLabel.Visible = 'off';

        app.MessageFrequencyfmLabel.Text = ...
        'Message Frequency (fm)';

        app.CarrierFrequencyfcLabel.Text = ...
        'Carrier Frequency (fc)';

        app.MessageFrequencyfmSlider.Limits = [100 5000];
        app.CarrierFrequencyfcSlider.Limits = [5000 20000];


    % =========================================
    % SSB-SC
    % =========================================
    case 'SSB-SC'

        app.ModulationIndexSlider.Visible = 'off';
        app.ModulationIndexSliderLabel.Visible = 'off';

        app.MessageFrequencyfmLabel.Text = ...
        'Message Frequency (fm)';

        app.CarrierFrequencyfcLabel.Text = ...
        'Carrier Frequency (fc)';

        app.MessageFrequencyfmSlider.Limits = [100 5000];
        app.CarrierFrequencyfcSlider.Limits = [5000 20000];


    % =========================================
    % FM
    % =========================================
    case 'FM'

        app.ModulationIndexSlider.Visible = 'on';
        app.ModulationIndexSliderLabel.Visible = 'on';

        app.ModulationIndexSliderLabel.Text = ...
        'FM Modulation Index';

        app.MessageFrequencyfmLabel.Text = ...
        'Message Frequency (fm)';

        app.CarrierFrequencyfcLabel.Text = ...
        'Carrier Frequency (fc)';

        app.MessageFrequencyfmSlider.Limits = [100 5000];
        app.CarrierFrequencyfcSlider.Limits = [5000 20000];


    % =========================================
    % TDM
    % =========================================
    case 'TDM'

        app.ModulationIndexSlider.Visible = 'on';
        app.ModulationIndexSliderLabel.Visible = 'on';

        app.ModulationIndexSliderLabel.Text = ...
        'Frame Size Control';

        app.MessageFrequencyfmLabel.Text = ...
        'Channel 1 Frequency';

        app.CarrierFrequencyfcLabel.Text = ...
        'Channel 2 Frequency';

        app.MessageFrequencyfmSlider.Limits = [50 500];
        app.CarrierFrequencyfcSlider.Limits = [50 500];


    % =========================================
    % FDM
    % =========================================
    case 'FDM'

        app.ModulationIndexSlider.Visible = 'off';
        app.ModulationIndexSliderLabel.Visible = 'off';

        app.MessageFrequencyfmLabel.Text = ...
        'Message Frequency 1';

        app.CarrierFrequencyfcLabel.Text = ...
        'Message Frequency 2';

        app.MessageFrequencyfmSlider.Limits = [10 200];
        app.CarrierFrequencyfcSlider.Limits = [10 200];

end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset dropdown
% Reset dropdown
app.SelectModulationDropDown.Value = ...
'AM-Amplitude Modulation';

% =========================
% RESET LABEL TEXTS
% =========================
app.MessageFrequencyfmLabel.Text = ...
'Message Frequency (fm)-';

app.CarrierFrequencyfcLabel.Text = ...
'Carrier Frequency (fc)-';

app.ModulationIndexSliderLabel.Text = ...
'Modulation Index-';

% =========================
% RESET SLIDER LIMITS
% =========================
app.MessageFrequencyfmSlider.Limits = [100 5000];

app.CarrierFrequencyfcSlider.Limits = [5000 20000];

app.ModulationIndexSlider.Limits = [0 5];

% =========================
% RESET SLIDER VALUES
% =========================
app.MessageFrequencyfmSlider.Value = 1000;

app.CarrierFrequencyfcSlider.Value = 10000;

app.ModulationIndexSlider.Value = 0.5;

% =========================
% SHOW MODULATION SLIDER
% =========================
app.ModulationIndexSlider.Visible = 'on';

app.ModulationIndexSliderLabel.Visible = 'on';

% =========================
% RESET MAIN SIGNAL AXES
% =========================
cla(app.UIAxes)

title(app.UIAxes,'Signal Output')

xlabel(app.UIAxes,'Time (s)')

ylabel(app.UIAxes,'Amplitude')

grid(app.UIAxes,'on')

app.UIAxes.Box = 'on';

% =========================
% RESET SPECTRUM AXES
% =========================
cla(app.SpectrumAxes)

title(app.SpectrumAxes,'Frequency Spectrum')

xlabel(app.SpectrumAxes,'Frequency (Hz)')

ylabel(app.SpectrumAxes,'Magnitude')

grid(app.SpectrumAxes,'on')

app.SpectrumAxes.Box = 'on';

% =========================
% CLEAR TEXT AREA
% =========================
app.TextArea.Value = {
'Simulation reset successfully'
'All parameters restored to default'
};

% =========================
% STATUS LABEL
% =========================
app.SimulationCompletedSuccessfullyLabel.Text = ...
'✔ System Reset Completed';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'AM Modulated Signal-')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.SpectrumAxes = uiaxes(app.UIFigure);
            title(app.SpectrumAxes, 'SpectrumAxes')
            xlabel(app.SpectrumAxes, 'Frequency')
            ylabel(app.SpectrumAxes, 'Magnitude')
            zlabel(app.SpectrumAxes, 'Z')
            app.ANALOGCOMMUNICATIONVIRTUALLABLabel = uilabel(app.UIFigure);
            app.ANALOGCOMMUNICATIONVIRTUALLABLabel.Text = 'ANALOG COMMUNICATION VIRTUAL LAB';
            app.ModulationSimulatorLabel = uilabel(app.UIFigure);
            app.ModulationSimulatorLabel.Text = 'Modulation Simulator';
            app.CONTROLSPanel = uipanel(app.UIFigure);
            app.CONTROLSPanel.Title = 'CONTROLS';
            app.SelectModulationLabel = uilabel(app.CONTROLSPanel);
            app.SelectModulationLabel.Text = '1. Select Modulation';
            app.SelectModulationDropDown = uidropdown(app.CONTROLSPanel);
            app.SelectModulationDropDown.Items = {'AM-Amplitude Modulation', 'DSB-SC', 'SSB-SC', 'FM', 'TDM', 'FDM'};
            app.SelectModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModulationDropDownValueChanged, true);
            app.SelectModulationDropDown.Value = 'AM-Amplitude Modulation';
            app.SignalParameterLabel = uilabel(app.CONTROLSPanel);
            app.SignalParameterLabel.Text = '2. Signal Parameter-';
            app.MessageFrequencyfmLabel = uilabel(app.CONTROLSPanel);
            app.MessageFrequencyfmLabel.Text = 'Message Frequency (fm)-';
            app.MessageFrequencyfmSlider = uislider(app.CONTROLSPanel);
            app.MessageFrequencyfmSlider.Limits = [100 5000];
            app.MessageFrequencyfmSlider.Value = 1000;
            app.CarrierFrequencyfcLabel = uilabel(app.CONTROLSPanel);
            app.CarrierFrequencyfcLabel.Text = 'Carrier Frequency (fc)-';
            app.CarrierFrequencyfcSlider = uislider(app.CONTROLSPanel);
            app.CarrierFrequencyfcSlider.Limits = [5000 20000];
            app.CarrierFrequencyfcSlider.Value = 10000;
            app.ModulationIndexSliderLabel = uilabel(app.CONTROLSPanel);
            app.ModulationIndexSliderLabel.Text = 'Modulation Index-';
            app.ModulationIndexSlider = uislider(app.CONTROLSPanel);
            app.ModulationIndexSlider.Limits = [0 5];
            app.ModulationIndexSlider.Value = 0.5;
            app.GenerateRunSimulationButton = uibutton(app.CONTROLSPanel, 'push');
            app.GenerateRunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRunSimulationButtonPushed, true);
            app.GenerateRunSimulationButton.Text = '▶ Generate / Run Simulation';
            app.ActionsLabel = uilabel(app.CONTROLSPanel);
            app.ActionsLabel.Text = '3. Actions';
            app.ResetButton = uibutton(app.CONTROLSPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = '↻ Reset';
            app.ShowDemodulatedSignalCheckBox = uicheckbox(app.CONTROLSPanel);
            app.ShowDemodulatedSignalCheckBox.Text = 'Show Demodulated Signal';
            app.SignalInformationPanel = uipanel(app.UIFigure);
            app.SignalInformationPanel.Title = 'Signal Information';
            app.Label = uilabel(app.SignalInformationPanel);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.SignalInformationPanel);
            app.TextArea.Editable = 'off';
            app.SimulationCompletedSuccessfullyLabel = uilabel(app.UIFigure);
            app.SimulationCompletedSuccessfullyLabel.Text = '✔ Simulation Completed Successfully';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end